classdef DTMF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu_Menu                  matlab.ui.container.Menu
        Menu_Help                  matlab.ui.container.Menu
        Menu_Programmer            matlab.ui.container.Menu
        Menu_EasterEgg             matlab.ui.container.Menu
        Menu_OpenEasterEgg         matlab.ui.container.Menu
        Menu_CloseEasterEgg        matlab.ui.container.Menu
        USTCNBLabel                matlab.ui.control.Label
        Button_Programmer          matlab.ui.control.Button
        Label_11                   matlab.ui.control.Label
        InformArea_2               matlab.ui.control.EditField
        Button_1                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Label_10                   matlab.ui.control.Label
        EditField_CaptureMoment    matlab.ui.control.EditField
        EditField_DecodeWav        matlab.ui.control.EditField
        Label_9                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        EditField_DecodeNow        matlab.ui.control.EditField
        Label_7                    matlab.ui.control.Label
        NoiseAmplitude             matlab.ui.control.Slider
        Label_6                    matlab.ui.control.Label
        NumberAmplitude            matlab.ui.control.Slider
        fsHzEditFieldLabel         matlab.ui.control.Label
        fsHzEditField              matlab.ui.control.NumericEditField
        sLabel_2                   matlab.ui.control.Label
        MaxNoiseLastTime           matlab.ui.control.Slider
        sLabel                     matlab.ui.control.Label
        NumberLastTime             matlab.ui.control.Slider
        Label_5                    matlab.ui.control.Label
        ForButtonAlreadyEntered    matlab.ui.control.EditField
        Label_2                    matlab.ui.control.Label
        InformArea                 matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        ForKeyboardAlreadyEntered  matlab.ui.control.EditField
        Button_A                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        Button_B                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        Button_8                   matlab.ui.control.Button
        Button_9                   matlab.ui.control.Button
        Button_C                   matlab.ui.control.Button
        Button_Star                matlab.ui.control.Button
        Button_0                   matlab.ui.control.Button
        Button_Well                matlab.ui.control.Button
        Button_D                   matlab.ui.control.Button
        ChooseInputMethod          matlab.ui.container.ButtonGroup
        KeyboardMethod             matlab.ui.control.RadioButton
        ButtonMethod               matlab.ui.control.RadioButton
        Keyboard                   matlab.ui.control.Label
        Button                     matlab.ui.control.Label
        Button_PlayAudio           matlab.ui.control.Button
        Button_SaveAudio           matlab.ui.control.Button
        Button_Help                matlab.ui.control.Button
        ForButtonReenter           matlab.ui.control.Button
        Button_StopPlayAudio       matlab.ui.control.Button
        DecodeNow                  matlab.ui.control.Button
        DecodeWav                  matlab.ui.control.Button
        Label_Capture              matlab.ui.control.Label
        Button_OpenEasterEgg       matlab.ui.control.Button
        Button_CloseEasterEgg      matlab.ui.control.Button
        Button_Capture             matlab.ui.control.Button
        TimeUIAxes                 matlab.ui.control.UIAxes
        DecodeTimeUIAxes           matlab.ui.control.UIAxes
        DecodeFrequencyUIAxes      matlab.ui.control.UIAxes
    end

    %大体思路
    
    %核心函数为CoreFunction %大多数回调中都会跑一遍CoreFunction
    
    %CoreFunction用的子函数为：
        %UseKeyboardToInput：用键盘输入电话号码
        %UseButtonToInput：用按钮输入电话号码
        %AddTwoSinFunction：画电话号码对应的时域波形
        %Decode：解码
        %Capture：截取
    
    %注释
        %几乎每一个模块都加了注释 %上下各空一行即为一个模块
        %几乎每一行都加了注释
        %注释≈这个模块、这一行是用来干啥的
    
    %启示：每个函数用到的【所有全局变量】、【所有局部变量】都可以【置0】
    
    properties (Access = public)%轩少能改的全局变量

        %当用户用键盘输入非法字符时要用
        flag_illegal=0;             
        
        %16个键：全局变量
        flag_Button_1=0;%按下Button_1时，flag_Button_1从0变为1，然后跑一次CoreFunction，然后flag_Button_1从1变为0 %其它flag同理
        flag_Button_2=0;
        flag_Button_3=0;
        flag_Button_4=0;
        flag_Button_5=0;
        flag_Button_6=0;
        flag_Button_7=0;
        flag_Button_8=0;
        flag_Button_9=0;
        flag_Button_0=0;
        flag_Button_A=0;
        flag_Button_B=0;
        flag_Button_C=0;
        flag_Button_D=0;
        flag_Button_Star=0;
        flag_Button_Well=0;
        
        %电话号码
        PhoneNumber='';%必须设置为全局变量，因为轩少在多个函数里都用了PhoneNumber
        
        %音频
        flag_Button_PlayAudio=0;
        flag_Button_SaveAudio=0;
        
        %为了按按钮时让用户实时听到声音
        SumOfTwoSin=double.empty;%即时域波形
        LastSumOfTwoSin=double.empty;%上一次的SumOfTwoSin        
        
        %为了让已经产生的随机噪声不重新产生
        MyMethod=0;%键盘、按钮方法要用
        LastRandomNoiseTime=zeros(1,100);%键盘、按钮方法要用 %用来储存上一次的RandomNoiseTime
        flag_MethodHasChanged=0;%键盘、按钮方法要用
        flag_ForKeyboardAlreadyEnteredValueChanged=0;%键盘方法要用
        NumberOfLastPhoneNumber=0; %按钮方法要用
        flag_ForButtonReenter=0;%按钮方法要用
        
        %Decode要用
        Wave_DecodeIndex=0; %Wave_DecodeIndex中存储的是Wave_Decode中某些元素的数组下标
        Wave_DecodeIndexIndex=0; %Wave_DecodeIndexIndex中存储的是Wave_DecodeIndex中某些元素的数组下标
        FFTFrequency=0;%FFT求出的Frequency
        DecodePhoneNumber='';%破译出的PhoneNumber
        
        %防止用户打开非DTMF要用
        flag_NotDTMF=0;
        
        %彩蛋要用
        flag_EasterEgg=0;
        flag_EasterEgg_Encode=0;
        flag_EasterEgg_Decode=0;
        flag_EasterEgg_Unlock=0;
        
        %为了解决每个号码的采样时间不同的问题
        flag_Decode=0;
        fs_wav=0;
        
        %截取要用
        y_wav=0;
        
    end
    
    methods (Access = private)%轩少能改的函数
        
        function CoreFunction(app)
            
            %置0
            app.InformArea.Value='';%置空
            app.InformArea_2.Value='';%置空
            cla(app.TimeUIAxes);%清空TimeUIAxes
            app.SumOfTwoSin=double.empty;%置空
            assignin('base','SumOfTwoSin',app.SumOfTwoSin);%发送到工作区，目的是检查程序是否出错
            
            %用键盘、按钮两种方法让用户拨打电话号码
            switch app.ChooseInputMethod.SelectedObject.Text
                case '键盘'
                    app.MyMethod=1;%MyMethod=1代表键盘
                case '按钮'
                    app.MyMethod=2;%MyMethod=2代表按钮SumOfTwoSin
            end
            if app.MyMethod==1
                UseKeyboardToInput(app);%函数见下
                app.ForButtonAlreadyEntered.Value='';%另一种方法置空
                if app.flag_illegal==1
                    app.flag_illegal=0;%置0 %启示：生效后就必须立刻置0 %启示：置0==回归初始状态
                    return;
                end                
            elseif app.MyMethod==2
                UseButtonToInput(app); %函数见下
                app.ForKeyboardAlreadyEntered.Value='';%另一种方法置空
            end

            %为了让已经产生的随机噪声不重新产生
            if app.flag_MethodHasChanged==1
                app.PhoneNumber='';%重新拨打
                app.LastRandomNoiseTime=zeros(1,100);%置0
                app.NumberOfLastPhoneNumber=0;%置0
            end            
            
            %为了重新拨打并且为了让已经产生的随机噪声不重新产生
            if app.MyMethod==2 && app.flag_ForButtonReenter==1
                app.PhoneNumber='';%重新拨打
                app.ForButtonAlreadyEntered.Value='';%重新拨打
                app.LastRandomNoiseTime=zeros(1,100);%置0
                app.NumberOfLastPhoneNumber=0;%置0
            end
            
            %画时域波形
            AddTwoSinFunction(app); %函数见下
                        
        end
        
        function UseKeyboardToInput(app)
            
            %求PhoneNumber
            app.PhoneNumber=app.ForKeyboardAlreadyEntered.Value;%直接回调即可
            
            %判断是否有非法字符
            SixteenNumber=double('0123456789ABCD*#');%SixteenNumber是double数组 %SixteenNumber包含16个键对应的double
            for i=1:length(app.PhoneNumber) %i从1跑到length(app.PhoneNumber)
                if ismember(double(app.PhoneNumber(i)),SixteenNumber)==0 %容易理解
                    app.InformArea.Value='号码中至少有一个不属于{0123456789ABCD*#}，请重新输入！'; %让用户看到
                    app.InformArea_2.Value='号码中至少有一个不属于{0123456789ABCD*#}，请重新输入！'; %让用户看到
                    app.flag_illegal=1;
                end
            end
            
        end
        
        function UseButtonToInput(app)
            
            %16个键：连接字符串 %并且能让用户看到自己已输入的电话号码
            if app.flag_Button_1==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'1');
            end
            if app.flag_Button_2==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'2');
            end
            if app.flag_Button_3==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'3');
            end
            if app.flag_Button_4==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'4');
            end
            if app.flag_Button_5==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'5');
            end
            if app.flag_Button_6==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'6');
            end
            if app.flag_Button_7==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'7');
            end
            if app.flag_Button_8==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'8');
            end
            if app.flag_Button_9==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'9');
            end
            if app.flag_Button_0==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'0');
            end
            if app.flag_Button_A==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'A');
            end
            if app.flag_Button_B==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'B');
            end
            if app.flag_Button_C==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'C');
            end
            if app.flag_Button_D==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'D');
            end
            if app.flag_Button_Star==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'*');
            end
            if app.flag_Button_Well==1
                app.ForButtonAlreadyEntered.Value=strcat(app.ForButtonAlreadyEntered.Value,'#');
            end
            
            %求PhoneNumber
            app.PhoneNumber=app.ForButtonAlreadyEntered.Value;%两种方法最后得到的字符串都是PhoneNumber
            
        end
        
        function AddTwoSinFunction(app)
            
            %设置参数
            fs =app.fsHzEditField.Value;%采样频率
            NumberTime=app.NumberLastTime.Value ;%单位为s
            app.SumOfTwoSin=double.empty;%必须置空，否则连接数组会出问题
            NoiseTimeAll=0;
            
            %求SumOfTwoSin %SumOfTwoSin为double数组
            for i=1:length(app.PhoneNumber)
                
                %求t_Number %t_Number用于每一段的号码波形
                if i==1
                    t_Number=NumberTime*(i-1):1/fs:NumberTime*(i);%≈树和树空
                end
                if i~=1
                    t_Number=NumberTime*(i-1)+1/fs:1/fs:NumberTime*(i);%≈树和树空
                end
                
                %求SumOfTwoSin，在每一段的号码处
                switch app.PhoneNumber(i)
                    %16个键：连接数组
                    %警告：请预先分配内存
                    %但轩少发现运算速度还不错
                    case '1'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*697*t_Number)+sin(2*pi*1209*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin%app.NumberAmplitude.Value为号码的幅度 %*1/2容易理解
                    case '2'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*697*t_Number)+sin(2*pi*1336*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '3'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*697*t_Number)+sin(2*pi*1477*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case 'A'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*697*t_Number)+sin(2*pi*1633*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '4'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*770*t_Number)+sin(2*pi*1209*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '5'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*770*t_Number)+sin(2*pi*1336*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '6'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*770*t_Number)+sin(2*pi*1477*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case 'B'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*770*t_Number)+sin(2*pi*1633*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '7'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*852*t_Number)+sin(2*pi*1209*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '8'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*852*t_Number)+sin(2*pi*1336*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '9'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*852*t_Number)+sin(2*pi*1477*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case 'C'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*852*t_Number)+sin(2*pi*1633*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '*'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*941*t_Number)+sin(2*pi*1209*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '0'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*941*t_Number)+sin(2*pi*1336*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case '#'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*941*t_Number)+sin(2*pi*1477*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                    case 'D'
                        app.SumOfTwoSin=[app.SumOfTwoSin (sin(2*pi*941*t_Number)+sin(2*pi*1633*t_Number))*app.NumberAmplitude.Value*1/2];%制造AddTwoSin
                end
                
                %为了让已经产生的随机噪声不重新产生 %求RandomNoiseTime %RandomNoiseTime为每一段的噪声的持续时间
                if app.MyMethod==1
                    if app.flag_ForKeyboardAlreadyEnteredValueChanged==1
                        RandomNoiseTime=(rand(1,1)*(app.MaxNoiseLastTime.Value-0.1)+0.1); %噪声时间最短为0.1s，最长为MaxNoiseLastTime
                        app.LastRandomNoiseTime(i)=RandomNoiseTime; %更新
                    end
                    if app.flag_ForKeyboardAlreadyEnteredValueChanged==0
                        RandomNoiseTime=app.LastRandomNoiseTime(i); %令本次的噪声时间和上一次的噪声时间相同
                    end
                end
                if app.MyMethod==2
                    if length(app.PhoneNumber)==app.NumberOfLastPhoneNumber+1
                        if i<=app.NumberOfLastPhoneNumber
                            RandomNoiseTime=app.LastRandomNoiseTime(i);%令本次的噪声时间和上一次的噪声时间相同
                        end
                        if i==app.NumberOfLastPhoneNumber+1
                            RandomNoiseTime=(rand(1,1)*(app.MaxNoiseLastTime.Value-0.1)+0.1); %噪声时间最短为0.1s，最长为MaxNoiseLastTime
                            app.LastRandomNoiseTime(i)=RandomNoiseTime; %更新
                        end
                    end
                    if length(app.PhoneNumber)==app.NumberOfLastPhoneNumber
                        RandomNoiseTime=app.LastRandomNoiseTime(i);%令本次的噪声时间和上一次的噪声时间相同
                    end
                end
                
                %求t_Noise %t_Noise用于每一段的噪声波形
                t_Noise=NumberTime*(i)+1/fs:1/fs:NumberTime*(i)+RandomNoiseTime;  %求t_Noise
                
                %求NoiseTimeAll
                NoiseTimeAll=NoiseTimeAll+RandomNoiseTime;
                
                %求SumOfTwoSin，在每一段的噪声处
                app.SumOfTwoSin=[app.SumOfTwoSin app.NoiseAmplitude.Value*(2*rand(1,length(t_Noise))-1)];%app.NoiseAmplitude.Value为噪声的幅度
                
                %发送到工作区，目的是检查程序是否出错
                assignin('base','SumOfTwoSin',app.SumOfTwoSin);%发送到工作区，目的是检查程序是否出错
                
            end
              
            %发送到工作区，目的是检查程序是否出错
            assignin('base','LastRandomNoiseTime',app.LastRandomNoiseTime);%发送到工作区，目的是检查程序是否出错
            
            %画时域波形
            if ~isempty(app.PhoneNumber)
                
                %画图
                TimeAll=0:1/fs:NumberTime*length(app.PhoneNumber)+NoiseTimeAll;%TimeAll为总时间
                assignin('base','TimeAll',TimeAll );%发送到工作区，目的是检查程序是否出错
                axis(app.TimeUIAxes,[0,TimeAll(end),min(app.SumOfTwoSin)*1.1,max(app.SumOfTwoSin)*1.1]) ;%设置x轴和y轴的范围
                MyLength=min(length(TimeAll),length(app.SumOfTwoSin));%改变滑块后，它们有可能有一些小差别 %≈树和树空                
                plot(app.TimeUIAxes,TimeAll(1:MyLength),app.SumOfTwoSin(1:MyLength));%画图
                
                %彩蛋要用
                app.flag_EasterEgg_Encode=1;%用户已画图，说明用户已经完成了Encode功能
                if app.flag_EasterEgg_Encode==1 && app.flag_EasterEgg_Decode==1 && app.flag_EasterEgg_Unlock==0
                    app.InformArea.Value='恭喜您，您已经解锁了彩蛋！！！';%提示用户
                    app.InformArea_2.Value='恭喜您，您已经解锁了彩蛋！！！';%提示用户         
                    app.flag_EasterEgg_Unlock=1;%目的：确保解锁提示只发送一次 
                    pause(5);
                end
                
                %播放音频  
                clear sound;                
                %键盘方法，直接播放
                if app.MyMethod==1&&app.flag_ForKeyboardAlreadyEnteredValueChanged==1
                    sound(app.SumOfTwoSin);%键盘方法，直接播放              
                end            
                %按钮方法，实时播放最新的号码及其噪声
                if app.MyMethod==2 && length(app.PhoneNumber)==app.NumberOfLastPhoneNumber+1
                    sound(app.SumOfTwoSin(length(app.LastSumOfTwoSin)+1:length(app.SumOfTwoSin)));%按钮方法，播放最新的号码及其噪声     
                    app.LastSumOfTwoSin=app.SumOfTwoSin;
                end               
                %用户点击"播放音频"按钮
                if app.flag_Button_PlayAudio==1
                    sound(app.SumOfTwoSin);
                end
                
                %把音频保存为结构体
                if app.flag_Button_SaveAudio==1
                    
                    %保存为structure
                    MyStructure.wavedata=app.SumOfTwoSin;
                    MyStructure.PhoneNumber=app.PhoneNumber;
                    MyStructure.fs=fs;
                    assignin('base','MyStructure',MyStructure);%发送到工作区，目的是检查程序是否出错
                    save('您拨打的电话号码','MyStructure');
                    
                    %保存为.wav
                    moment=datestr(now,30);%30代表第30种格式
                    filename = strcat(moment,'.wav');%保存为wav文件                    
                    SumOfTwoSin_Record=app.SumOfTwoSin/abs(max(app.SumOfTwoSin));%audiowrite只能存[-1,+1]，因此必须归一化
                    audiowrite(filename,SumOfTwoSin_Record,fs);%保存为wav文件     
                    
                end
                
            end
            
            %为了让已经产生的随机噪声不重新产生 %更新NumberOfLastPhoneNumber
            if app.MyMethod==2
                app.NumberOfLastPhoneNumber=length(app.PhoneNumber);   %更新
            end
      
        end
        
        function Decode(app,Wave_Decode,fs_Decode)

            app.Wave_DecodeIndex=0; %置0
            app.Wave_DecodeIndexIndex=0; %置0
            app.FFTFrequency=0;%置0
            app.DecodePhoneNumber='';%置0                   
            
            %若Wave_Decode为空，直接return
            if isempty(Wave_Decode) 
                return;
            end
            
            %程玉锟帮轩少改进了一下，程序运行速度大大提高
            data1=abs(Wave_Decode-max(Wave_Decode))<0.1*max(Wave_Decode);
            app.Wave_DecodeIndex=find(data1);
            
%             %取出Wave_Decode中接近最大值的那些点
%             j=1;%置1
%             for i=1:length(Wave_Decode)
%                 if abs(Wave_Decode(i)-max(Wave_Decode))<0.1*max(Wave_Decode) %现实中，号码段也有噪声，因此轩少把绝对值上限设为0.1*max(Wave_Decode)
%                     app.Wave_DecodeIndex(j)=i;%取出下标
%                     j=j+1;
%                 end
%             end
            
            %取出上述点中数组下标相差＞700的点 %每秒采样率至少为8000，噪声至少为0.1s，因此两个号码之间的采样点的下标必然>700
            j=1;%置1
            for i=1:length(app.Wave_DecodeIndex)
                if i==1%第一个下标需要保留
                    app.Wave_DecodeIndexIndex(j)=i;%取出下标
                    j=j+1;
                end
                if i>=2 && app.Wave_DecodeIndex(i)-app.Wave_DecodeIndex(i-1)>700
                    app.Wave_DecodeIndexIndex(j)=i-1;%取出下标
                    j=j+1;
                    app.Wave_DecodeIndexIndex(j)=i;%取出下标
                    j=j+1;
                end
                if i==length(app.Wave_DecodeIndex)%最后一个下标需要保留
                    app.Wave_DecodeIndexIndex(j)=i;%取出下标
                    j=j+1;
                end
            end
            
            %fft
            for i=1:2:length(app.Wave_DecodeIndexIndex)      
                
                %fft
                NumberPartOfWave_Decode=Wave_Decode(app.Wave_DecodeIndex(app.Wave_DecodeIndexIndex(i)):app.Wave_DecodeIndex(app.Wave_DecodeIndexIndex(i+1)));%取出NumberPartOfWave_Decode 
                Bilateral=abs(fft(NumberPartOfWave_Decode));%双边
                Unilateral=Bilateral(1:round(length(Bilateral)/2));%单边 %round为就近取整
                
                %非DTMF：length(Unilateral)<=2
                if length(Unilateral)<=2%易知，此时音频必然不是DTMF
                    app.InformArea.Value='您的音频不是DTMF，即不是由2个正弦波叠加形成的';%提示用户
                    app.InformArea_2.Value='您的音频不是DTMF，即不是由2个正弦波叠加形成的';%提示用户
                    app.flag_NotDTMF=1;
                    return;
                end              
                
                %寻峰
                [~,location]=findpeaks(Unilateral,'minpeakheight',max(Unilateral)*1/2);%轩少把minpeakheight设为max(Unilateral)*1/2
                
                %非DTMF：length(location)~=2
                if length(location)~=2%若寻峰得到的峰不是2个，则音频必然不是GTMF
                    app.InformArea.Value='您的音频不是DTMF，即不是由2个正弦波叠加形成的';%提示用户
                    app.InformArea_2.Value='您的音频不是DTMF，即不是由2个正弦波叠加形成的';%提示用户
                    app.flag_NotDTMF=1;
                    return;
                end
                
                SampleTime=length(NumberPartOfWave_Decode)/fs_Decode;%每个号码的SampleTime可以是不同的
                
%                 %这里可以再改改
%                 if app.flag_Decode==1
%                     fs=app.fsHzEditField.Value;%采样频率
%                     SampleTime=app.NumberLastTime.Value;%采样时间
%                 end
%                 
%                 if app.flag_Decode==2
%                     fs=app.fs_wav;
%                     SampleTime=length(NumberPartOfWave_Decode)/fs;%每个号码的SampleTime可以是不同的
%                 end
                                               
                app.FFTFrequency=fs_Decode*(location/(SampleTime*fs_Decode+1));%换算
                
                assignin('base','FFTFrequency',app.FFTFrequency);%发送到工作区，目的是检查程序是否出错
                
                %16个键
                if abs(app.FFTFrequency(1)-697)<20 && abs(app.FFTFrequency(2)-1209)<30
                     app.DecodePhoneNumber((i+1)/2)='1';
                elseif abs(app.FFTFrequency(1)-697)<20 && abs(app.FFTFrequency(2)-1336)<30 
                     app.DecodePhoneNumber((i+1)/2)='2';
                elseif abs(app.FFTFrequency(1)-697)<20 && abs(app.FFTFrequency(2)-1477)<30 
                     app.DecodePhoneNumber((i+1)/2)='3';         
                elseif abs(app.FFTFrequency(1)-697)<20 && abs(app.FFTFrequency(2)-1633)<30 
                     app.DecodePhoneNumber((i+1)/2)='A';           
                elseif abs(app.FFTFrequency(1)-770)<20 && abs(app.FFTFrequency(2)-1209)<30 
                     app.DecodePhoneNumber((i+1)/2)='4';          
                elseif abs(app.FFTFrequency(1)-770)<20 && abs(app.FFTFrequency(2)-1336)<30 
                     app.DecodePhoneNumber((i+1)/2)='5';
                elseif abs(app.FFTFrequency(1)-770)<20 && abs(app.FFTFrequency(2)-1477)<30 
                     app.DecodePhoneNumber((i+1)/2)='6';         
                elseif abs(app.FFTFrequency(1)-770)<20 && abs(app.FFTFrequency(2)-1633)<30 
                     app.DecodePhoneNumber((i+1)/2)='B';         
                elseif abs(app.FFTFrequency(1)-852)<20 && abs(app.FFTFrequency(2)-1209)<30 
                     app.DecodePhoneNumber((i+1)/2)='7';          
                elseif abs(app.FFTFrequency(1)-852)<20 && abs(app.FFTFrequency(2)-1336)<30 
                     app.DecodePhoneNumber((i+1)/2)='8';
                elseif abs(app.FFTFrequency(1)-852)<20 && abs(app.FFTFrequency(2)-1477)<30 
                     app.DecodePhoneNumber((i+1)/2)='9';         
                elseif abs(app.FFTFrequency(1)-852)<20 && abs(app.FFTFrequency(2)-1633)<30 
                     app.DecodePhoneNumber((i+1)/2)='C';     
                elseif abs(app.FFTFrequency(1)-941)<20 && abs(app.FFTFrequency(2)-1209)<30 
                     app.DecodePhoneNumber((i+1)/2)='*';          
                elseif abs(app.FFTFrequency(1)-941)<20 && abs(app.FFTFrequency(2)-1336)<30 
                     app.DecodePhoneNumber((i+1)/2)='0';
                elseif abs(app.FFTFrequency(1)-941)<20 && abs(app.FFTFrequency(2)-1477)<30 
                     app.DecodePhoneNumber((i+1)/2)='#';         
                elseif abs(app.FFTFrequency(1)-941)<20 && abs(app.FFTFrequency(2)-1633)<30 
                     app.DecodePhoneNumber((i+1)/2)='D';   
                else
                     app.InformArea.Value='您的音频不是DTMF或虽是DTMF但不是16个键的标准DTMF';%提示用户
                     app.InformArea_2.Value='您的音频不是DTMF或虽是DTMF但不是16个键的标准DTMF';%提示用户
                     app.flag_NotDTMF=1;
                     return;
                end   
                assignin('base','DecodePhoneNumber',app.DecodePhoneNumber);%发送到工作区，目的是检查程序是否出错
            end            
        end
        
        function Capture(app,Wave_Capture,fs_Capture)
            
            %截取
            CurrentPointArray=app.DecodeTimeUIAxes.CurrentPoint; %CurrentPointArray为2x3数组
            CurrentPoint=[CurrentPointArray(1,1) CurrentPointArray(1,2)]; %CurrentPoint为(x,y)
            t_Capture=CurrentPointArray(1,1);%CurrentPointArray(1,1)为横坐标
            assignin('base','CurrentPoint',CurrentPoint);%发送到工作区，目的是检查程序是否出错
            
            if t_Capture>=0 && t_Capture+0.03<=length(Wave_Capture)/fs_Capture %容易理解
                
                %截取
                Capture30ms=Wave_Capture(round(t_Capture*fs_Capture):round((t_Capture+0.03)*fs_Capture));%0.03s=30ms %round为就近取整
                
                %fft
                Bilateral_Capture30ms=abs(fft(Capture30ms));%双边
                Unilateral_Capture30ms=Bilateral_Capture30ms(1:round(length(Bilateral_Capture30ms)/2));%单边 %round为就近取整
                
                %画图
                plot(app.DecodeFrequencyUIAxes,Unilateral_Capture30ms);%画频域图
                
                %提示用户
                app.EditField_CaptureMoment.Value=strcat(num2str(t_Capture),'s');%num2str：把double转换为char %strcat：连接字符串
                app.InformArea.Value='截取已完成，频域中为您截取的30ms时域图像的傅里叶变换';%提示用户
                app.InformArea_2.Value='截取已完成，频域中为您截取的30ms时域图像的傅里叶变换';%提示用户
                
            elseif t_Capture<=length(Wave_Capture)/fs_Capture && t_Capture+0.03>length(Wave_Capture)/fs_Capture
                
                %提示用户
                app.InformArea.Value='嘿，您截取的时刻加上0.03s后超过了音频的总时长！！！';%提示用户
                app.InformArea_2.Value='嘿，您截取的时刻加上0.03s后超过了音频的总时长！！！';%提示用户
                
            else
                
                %提示用户
                app.InformArea.Value='嘿，您没有"先点左图，再点截取30ms按钮"！！！';%提示用户
                app.InformArea_2.Value='嘿，您没有"先点左图，再点截取30ms按钮"！！！';%提示用户
                
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %运行CoreFunction
            CoreFunction(app);
            
            %提示用户
            app.InformArea.Value='若您是第一次使用，请先点击左上角的"帮助"按钮哦~~~';%提示用户
            app.InformArea_2.Value='若您是第一次使用，请先点击左上角的"帮助"按钮哦~~~';%提示用户
                        
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_1=1;
            CoreFunction(app);
            app.flag_Button_1=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1                               
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\1.wav');
                sound(y_EasterEgg,fs_EasterEgg)
            end
            
        end

        % Value changed function: ForKeyboardAlreadyEntered
        function ForKeyboardAlreadyEnteredValueChanged(app, event)
            app.flag_ForKeyboardAlreadyEnteredValueChanged=1;
            CoreFunction(app);
            app.flag_ForKeyboardAlreadyEnteredValueChanged=0;
        end

        % Value changed function: InformArea
        function InformAreaValueChanged(app, event)
            
        end

        % Selection changed function: ChooseInputMethod
        function ChooseInputMethodSelectionChanged(app, event)
            app.flag_MethodHasChanged=1;
            CoreFunction(app);
            app.flag_MethodHasChanged=0;
        end

        % Value changed function: ForButtonAlreadyEntered
        function ForButtonAlreadyEnteredValueChanged(app, event)
            %             value = app.ForButtonAlreadyEntered.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            %和彩蛋无关
            app.flag_Button_2=1;
            CoreFunction(app);
            app.flag_Button_2=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1                
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\2.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %图片
                imshow(imread('彩蛋\2.jpg'));                
            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            %和彩蛋无关
            app.flag_Button_3=1;
            CoreFunction(app);
            app.flag_Button_3=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1                
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\3.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %图片
                imshow(imread('彩蛋\3.jpg')); 
                %文字
                app.InformArea.Value='希望2021-2022赛季艾伦和继伟能拿到总冠军';%彩蛋
                app.InformArea_2.Value='希望2021-2022赛季艾伦和继伟能拿到总冠军';%彩蛋
            end
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
 
            %和彩蛋无关
            app.flag_Button_4=1;
            CoreFunction(app);
            app.flag_Button_4=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1                
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\4.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %文字
                app.InformArea.Value='阿森纳球迷不要打我~~~';%彩蛋
                app.InformArea_2.Value='阿森纳球迷不要打我~~~';%彩蛋
            end
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_5=1;
            CoreFunction(app);
            app.flag_Button_5=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\5.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %图片
                imshow(imread('彩蛋\5.jpg')); 
                %文字
                app.InformArea.Value='伍豪';%彩蛋
                app.InformArea_2.Value='伍豪';%彩蛋
            end
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_6=1;
            CoreFunction(app);
            app.flag_Button_6=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\6.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %图片
                imshow(imread('彩蛋\6.jpg')); 
            end            
            
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_7=1;
            CoreFunction(app);
            app.flag_Button_7=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\7.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %图片
                imshow(imread('彩蛋\7.png')); 
            end
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
           
            %和彩蛋无关
            app.flag_Button_8=1;
            CoreFunction(app);
            app.flag_Button_8=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\8.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %图片
                imshow(imread('彩蛋\8.jpg')); 
            end
            
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_9=1;
            CoreFunction(app);
            app.flag_Button_9=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\9.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %图片
                imshow(imread('彩蛋\9.jpg')); 
            end
            
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_0=1;
            CoreFunction(app);
            app.flag_Button_0=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\0.wav');
                sound(y_EasterEgg,fs_EasterEgg)
            end
            
        end

        % Button pushed function: Button_A
        function Button_APushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_A=1;
            CoreFunction(app);
            app.flag_Button_A=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\A.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %视频
                implay('彩蛋\A.mp4');
            end
            
        end

        % Button pushed function: Button_B
        function Button_BPushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_B=1;
            CoreFunction(app);
            app.flag_Button_B=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\B.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %文字
                app.InformArea.Value='B是轩少最爱的一个彩蛋';%彩蛋
                app.InformArea_2.Value='B是轩少最爱的一个彩蛋';%彩蛋
            end
            
        end

        % Button pushed function: Button_C
        function Button_CPushed(app, event)
           
            %和彩蛋无关
            app.flag_Button_C=1;
            CoreFunction(app);
            app.flag_Button_C=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\C.wav');
                sound(y_EasterEgg,fs_EasterEgg)
            end            
            
        end

        % Button pushed function: Button_D
        function Button_DPushed(app, event)
            
            %和彩蛋无关
            app.flag_Button_D=1;
            CoreFunction(app);
            app.flag_Button_D=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\D.wav');
                sound(y_EasterEgg,fs_EasterEgg)
            end 
            
        end

        % Button pushed function: Button_Star
        function Button_StarPushed(app, event)
            
            %和彩蛋无关 
            app.flag_Button_Star=1;
            CoreFunction(app);
            app.flag_Button_Star=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\asterisk.wav');
                sound(y_EasterEgg,fs_EasterEgg)
            end 
            
        end

        % Button pushed function: Button_Well
        function Button_WellPushed(app, event)
            
            %和彩蛋无关 
            app.flag_Button_Well=1;
            CoreFunction(app);
            app.flag_Button_Well=0;%置0
            
            %和彩蛋有关
            if app.flag_EasterEgg==1
                %音频
                clear sound;
                [y_EasterEgg,fs_EasterEgg]=audioread('彩蛋\NumberSign.wav');
                sound(y_EasterEgg,fs_EasterEgg)
                %图片
                imshow(imread('彩蛋\NumberSign.png'));
                %文字
                app.InformArea.Value='“#”在英语中叫number sign，你知道嘛？';%彩蛋
                app.InformArea_2.Value='"#"在英语中叫number sign，你知道嘛？';%彩蛋
            end 
            
        end

        % Value changed function: MaxNoiseLastTime
        function MaxNoiseLastTimeValueChanged(app, event)
            CoreFunction(app);
        end

        % Value changed function: NumberLastTime
        function NumberLastTimeValueChanged(app, event)
            CoreFunction(app);
        end

        % Value changed function: fsHzEditField
        function fsHzEditFieldValueChanged(app, event)
            CoreFunction(app);
        end

        % Button pushed function: Button_PlayAudio
        function Button_PlayAudioPushed(app, event)
            app.flag_Button_PlayAudio=1;
            CoreFunction(app);
            app.flag_Button_PlayAudio=0;%置0
        end

        % Button pushed function: Button_SaveAudio
        function Button_SaveAudioPushed(app, event)
            app.flag_Button_SaveAudio=1;
            CoreFunction(app);
            app.flag_Button_SaveAudio=0;%置0
            app.InformArea.Value='音频已保存！！！';%提示用户
            app.InformArea_2.Value='音频已保存！！！';%提示用户
        end

        % Value changed function: NumberAmplitude
        function NumberAmplitudeValueChanged(app, event)
            CoreFunction(app);
        end

        % Value changed function: NoiseAmplitude
        function NoiseAmplitudeValueChanged(app, event)
            CoreFunction(app);
        end

        % Button pushed function: ForButtonReenter
        function ForButtonReenterPushed(app, event)
            app.flag_ForButtonReenter=1;
            CoreFunction(app);
            app.flag_ForButtonReenter=0;
        end

        % Button pushed function: Button_StopPlayAudio
        function Button_StopPlayAudioPushed(app, event)
            clear sound;
        end

        % Button pushed function: DecodeNow
        function DecodeNowButtonPushed(app, event)
            
            %截取要用
            app.flag_Decode=1;
            
            %若app.SumOfTwoSin is empty，直接return
            if isempty(app.SumOfTwoSin)
                return;              
            end
            
            %置0
            app.EditField_DecodeNow.Value='';%置空
            app.EditField_DecodeWav.Value='';%置空 
            app.EditField_CaptureMoment.Value='';%置空            
            cla(app.DecodeTimeUIAxes);%清空
            cla(app.DecodeFrequencyUIAxes);%清空    
            
            %播放
            sound(app.SumOfTwoSin);%播放一下，用户体验更好 
            
            %调用Decode函数
            Decode(app,app.SumOfTwoSin,app.fsHzEditField.Value);
            
            %让用户看到DecodePhoneNumber
            app.EditField_DecodeNow.Value=app.DecodePhoneNumber;
            
            %画图
            fs=app.fsHzEditField.Value;
            TimeDecodeNow=1/fs:1/fs:length(app.SumOfTwoSin)/fs;
            axis(app.DecodeTimeUIAxes,[0,length(app.SumOfTwoSin)/fs,min(app.SumOfTwoSin)*1.1,max(app.SumOfTwoSin)*1.1]) ;%设置x轴和y轴的范围
            plot(app.DecodeTimeUIAxes,TimeDecodeNow,app.SumOfTwoSin);%画图     
            
            %彩蛋要用
            app.flag_EasterEgg_Decode=1;%用户已画图，说明用户已经完成了Decode功能
            if app.flag_EasterEgg_Encode==1 && app.flag_EasterEgg_Decode==1 && app.flag_EasterEgg_Unlock==0
                app.InformArea.Value='恭喜您，您已经解锁了彩蛋！！！';%提示用户
                app.InformArea_2.Value='恭喜您，您已经解锁了彩蛋！！！';%提示用户
                app.flag_EasterEgg_Unlock=1;%目的：确保解锁提示只发送一次
                pause(5);
            end
            
            %提示用户
            app.InformArea.Value='译码已完成！！！';
            app.InformArea_2.Value='译码已完成！！！';
                        
        end

        % Value changed function: EditField_DecodeNow
        function EditField_DecodeNowValueChanged(app, event)
            
        end

        % Button pushed function: Button_OpenEasterEgg
        function Button_OpenEasterEggPushed(app, event)
            
            %用户必须先实现编码和译码两大功能，然后才能玩彩蛋
            if app.flag_EasterEgg_Encode==0 || app.flag_EasterEgg_Decode==0 
                app.InformArea.Value='嘿，您还没有解锁彩蛋哦~~~';%提示用户
                app.InformArea_2.Value='嘿，您还没有解锁彩蛋哦~~~';%提示用户
                return;%直接返回
            end            
            
            app.flag_EasterEgg=1;%置1
            assignin('base','flag_EasterEgg',app.flag_EasterEgg);%发送到工作区，目的是检查程序是否出错 
            app.InformArea.Value='彩蛋已打开！！！请再试试用按钮方法拨打电话号码吧~~~';%提示用户
            app.InformArea_2.Value='彩蛋已打开！！！请再试试用按钮方法拨打电话号码吧~~~';%提示用户
            
        end

        % Button pushed function: Button_CloseEasterEgg
        function Button_CloseEasterEggPushed(app, event)
            
            %提示用户
            if app.flag_EasterEgg==0 %用户还没打开彩蛋
                app.InformArea.Value='嘿，您还没有打开彩蛋呢';%提示用户
                app.InformArea_2.Value='嘿，您还没有打开彩蛋呢';%提示用户
                return;%直接返回
            end  
            
            app.flag_EasterEgg=0;%置0
            assignin('base','flag_EasterEgg',app.flag_EasterEgg);%发送到工作区，目的是检查程序是否出错             
            app.InformArea.Value='彩蛋已关闭！！！';%提示用户            
            app.InformArea_2.Value='彩蛋已关闭！！！';%提示用户
            
        end

        % Button pushed function: Button_Capture
        function Button_CapturePushed(app, event)
            
            %置0
            app.InformArea.Value='';%置0
            app.InformArea_2.Value='';%置0
                
            %破译当前时域信号
            if app.flag_Decode==1
                Capture(app,app.SumOfTwoSin,app.fsHzEditField.Value);
            end
            
            %破译wav文件
            if app.flag_Decode==2
                Capture(app,app.y_wav,app.fs_wav);             
            end
            
        end

        % Value changed function: EditField_CaptureMoment
        function EditField_CaptureMomentValueChanged(app, event)
%             value = app.EditField_CaptureMoment.Value;
            
        end

        % Button pushed function: DecodeWav
        function DecodeWavButtonPushed(app, event)
            
            %截取要用
            app.flag_Decode=2;
            
            %置0
            app.EditField_DecodeNow.Value='';%置空 
            app.EditField_DecodeWav.Value='';%置空
            app.EditField_CaptureMoment.Value='';%置空
            cla(app.DecodeTimeUIAxes);%清空
            cla(app.DecodeFrequencyUIAxes);%清空
            
            %打开.wav文件
            [FilenameRead,path]=uigetfile;
            if FilenameRead==0 
                return;%若在uigetfile中用户点击关闭，则FilenameRead会被matlab设置为0，此时轩少选择直接return
            end
            if contains(FilenameRead,'.wav')%contains：若第一个字符串包含第二个字符串，返回1，否则返回0
                [app.y_wav,app.fs_wav]=audioread(strcat(path,FilenameRead));
                player = audioplayer(app.y_wav,app.fs_wav);
                play(player);%播放一下，用户体验更好 %play函数恰好会在Decode之后自动停止播放，因此此处用play而不用sound
            else 
                app.InformArea.Value='嘿，您点击的文件不是.wav文件！！！';%提示用户
                app.InformArea_2.Value='嘿，您点击的文件不是.wav文件！！！';%提示用户
                return;%直接return
            end   
                           
            %调用Decode函数
            Decode(app,app.y_wav,app.fs_wav);
            
            if app.flag_NotDTMF==1
                app.flag_NotDTMF=0;%置0
                return;%直接返回
            end
            
            %让用户看到DecodePhoneNumber
            app.EditField_DecodeWav.Value=app.DecodePhoneNumber;
            
            %画图
            t_wav=0+1/app.fs_wav:1/app.fs_wav:length(app.y_wav)/app.fs_wav; 
            axis(app.DecodeTimeUIAxes,[0,length(app.y_wav)/app.fs_wav,min(app.y_wav)*1.1,max(app.y_wav)*1.1]) ;%设置x轴和y轴的范围
            plot(app.DecodeTimeUIAxes,t_wav,app.y_wav);%画图     
            
            %提示用户
            app.InformArea.Value='译码已完成！！！';
            app.InformArea_2.Value='译码已完成！！！';

        end

        % Value changed function: EditField_DecodeWav
        function EditField_DecodeWavValueChanged(app, event)
%             value = app.EditField_DecodeWav.Value;            
        end

        % Value changed function: InformArea_2
        function InformArea_2ValueChanged(app, event)
%             value = app.InformArea_2.Value;            
        end

        % Button pushed function: Button_Help
        function Button_HelpPushed(app, event)
            open 帮助.pdf;
        end

        % Menu selected function: Menu_Help
        function Menu_HelpSelected(app, event)
            open 帮助.pdf;            
        end

        % Menu selected function: Menu_Programmer
        function Menu_ProgrammerSelected(app, event)
            open Programmer.pdf;
        end

        % Button pushed function: Button_Programmer
        function Button_ProgrammerPushed(app, event)
            open Programmer.pdf;
        end

        % Menu selected function: Menu_OpenEasterEgg
        function Menu_OpenEasterEggSelected(app, event)
            
            %用户必须先实现编码和译码两大功能，然后才能玩彩蛋
            if app.flag_EasterEgg_Encode==0 || app.flag_EasterEgg_Decode==0 
                app.InformArea.Value='嘿，您还没有解锁彩蛋哦~~~';%提示用户
                app.InformArea_2.Value='嘿，您还没有解锁彩蛋哦~~~';%提示用户
                return;%直接返回
            end            
            
            app.flag_EasterEgg=1;%置1
            assignin('base','flag_EasterEgg',app.flag_EasterEgg);%发送到工作区，目的是检查程序是否出错 
            app.InformArea.Value='彩蛋已打开！！！请再试试用按钮方法拨打电话号码吧~~~';%提示用户
            app.InformArea_2.Value='彩蛋已打开！！！请再试试用按钮方法拨打电话号码吧~~~';%提示用户
            
        end

        % Menu selected function: Menu_CloseEasterEgg
        function Menu_CloseEasterEggSelected(app, event)
 
            %提示用户
            if app.flag_EasterEgg==0 %用户还没打开彩蛋
                app.InformArea.Value='嘿，您还没有打开彩蛋呢';%提示用户
                app.InformArea_2.Value='嘿，您还没有打开彩蛋呢';%提示用户
                return;%直接返回
            end  
            
            app.flag_EasterEgg=0;%置0
            assignin('base','flag_EasterEgg',app.flag_EasterEgg);%发送到工作区，目的是检查程序是否出错             
            app.InformArea.Value='彩蛋已关闭！！！';%提示用户            
            app.InformArea_2.Value='彩蛋已关闭！！！';%提示用户     
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu_Menu = uimenu(app.UIFigure);
            app.Menu_Menu.Text = '菜单';
            app.Menu_Help = uimenu(app.Menu_Menu);
            app.Menu_Help.MenuSelectedFcn = createCallbackFcn(app, @Menu_HelpSelected, true);
            app.Menu_Help.Text = '帮助';
            app.Menu_Programmer = uimenu(app.Menu_Menu);
            app.Menu_Programmer.MenuSelectedFcn = createCallbackFcn(app, @Menu_ProgrammerSelected, true);
            app.Menu_Programmer.Text = '编写者';
            app.Menu_EasterEgg = uimenu(app.UIFigure);
            app.Menu_EasterEgg.Text = '彩蛋';
            app.Menu_OpenEasterEgg = uimenu(app.Menu_EasterEgg);
            app.Menu_OpenEasterEgg.MenuSelectedFcn = createCallbackFcn(app, @Menu_OpenEasterEggSelected, true);
            app.Menu_OpenEasterEgg.Text = '打开彩蛋';
            app.Menu_CloseEasterEgg = uimenu(app.Menu_EasterEgg);
            app.Menu_CloseEasterEgg.MenuSelectedFcn = createCallbackFcn(app, @Menu_CloseEasterEggSelected, true);
            app.Menu_CloseEasterEgg.Text = '关闭彩蛋';
            app.DecodeFrequencyUIAxes = uiaxes(app.UIFigure);
            title(app.DecodeFrequencyUIAxes, '频域')
            xlabel(app.DecodeFrequencyUIAxes, 'f')
            ylabel(app.DecodeFrequencyUIAxes, 'A')
            zlabel(app.DecodeFrequencyUIAxes, 'Z')
            app.DecodeTimeUIAxes = uiaxes(app.UIFigure);
            title(app.DecodeTimeUIAxes, '时域')
            xlabel(app.DecodeTimeUIAxes, 't')
            ylabel(app.DecodeTimeUIAxes, 'A')
            zlabel(app.DecodeTimeUIAxes, 'Z')
            app.TimeUIAxes = uiaxes(app.UIFigure);
            title(app.TimeUIAxes, '时域')
            xlabel(app.TimeUIAxes, 't')
            ylabel(app.TimeUIAxes, 'A')
            zlabel(app.TimeUIAxes, 'Z')
            app.Button_Capture = uibutton(app.UIFigure, 'push');
            app.Button_Capture.ButtonPushedFcn = createCallbackFcn(app, @Button_CapturePushed, true);
            app.Button_Capture.Text = '截取30ms';
            app.Button_CloseEasterEgg = uibutton(app.UIFigure, 'push');
            app.Button_CloseEasterEgg.ButtonPushedFcn = createCallbackFcn(app, @Button_CloseEasterEggPushed, true);
            app.Button_CloseEasterEgg.Text = '关闭彩蛋';
            app.Button_OpenEasterEgg = uibutton(app.UIFigure, 'push');
            app.Button_OpenEasterEgg.ButtonPushedFcn = createCallbackFcn(app, @Button_OpenEasterEggPushed, true);
            app.Button_OpenEasterEgg.Text = '打开彩蛋';
            app.Label_Capture = uilabel(app.UIFigure);
            app.Label_Capture.Text = '先点左图，再点下方按钮';
            app.DecodeWav = uibutton(app.UIFigure, 'push');
            app.DecodeWav.ButtonPushedFcn = createCallbackFcn(app, @DecodeWavButtonPushed, true);
            app.DecodeWav.Text = '破译wav文件';
            app.DecodeNow = uibutton(app.UIFigure, 'push');
            app.DecodeNow.ButtonPushedFcn = createCallbackFcn(app, @DecodeNowButtonPushed, true);
            app.DecodeNow.Text = '破译右上角图中的信号';
            app.Button_StopPlayAudio = uibutton(app.UIFigure, 'push');
            app.Button_StopPlayAudio.ButtonPushedFcn = createCallbackFcn(app, @Button_StopPlayAudioPushed, true);
            app.Button_StopPlayAudio.Text = '停止播放';
            app.ForButtonReenter = uibutton(app.UIFigure, 'push');
            app.ForButtonReenter.ButtonPushedFcn = createCallbackFcn(app, @ForButtonReenterPushed, true);
            app.ForButtonReenter.Text = '按钮方法：重新拨打';
            app.Button_Help = uibutton(app.UIFigure, 'push');
            app.Button_Help.ButtonPushedFcn = createCallbackFcn(app, @Button_HelpPushed, true);
            app.Button_Help.Text = '帮助';
            app.Button_SaveAudio = uibutton(app.UIFigure, 'push');
            app.Button_SaveAudio.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveAudioPushed, true);
            app.Button_SaveAudio.Text = '保存';
            app.Button_PlayAudio = uibutton(app.UIFigure, 'push');
            app.Button_PlayAudio.ButtonPushedFcn = createCallbackFcn(app, @Button_PlayAudioPushed, true);
            app.Button_PlayAudio.Text = '开始播放';
            app.Button = uilabel(app.UIFigure);
            app.Button.Text = '按钮方法';
            app.Keyboard = uilabel(app.UIFigure);
            app.Keyboard.Text = '键盘方法';
            app.ChooseInputMethod = uibuttongroup(app.UIFigure);
            app.ChooseInputMethod.SelectionChangedFcn = createCallbackFcn(app, @ChooseInputMethodSelectionChanged, true);
            app.ChooseInputMethod.Title = '您想选择哪种方法拨打电话号码？';
            app.ButtonMethod = uiradiobutton(app.ChooseInputMethod);
            app.ButtonMethod.Text = '键盘';
            app.KeyboardMethod = uiradiobutton(app.ChooseInputMethod);
            app.KeyboardMethod.Text = '按钮';
            app.KeyboardMethod.Value = true;
            app.Button_D = uibutton(app.UIFigure, 'push');
            app.Button_D.ButtonPushedFcn = createCallbackFcn(app, @Button_DPushed, true);
            app.Button_D.Text = 'D';
            app.Button_Well = uibutton(app.UIFigure, 'push');
            app.Button_Well.ButtonPushedFcn = createCallbackFcn(app, @Button_WellPushed, true);
            app.Button_Well.Text = '#';
            app.Button_0 = uibutton(app.UIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.Button_Star = uibutton(app.UIFigure, 'push');
            app.Button_Star.ButtonPushedFcn = createCallbackFcn(app, @Button_StarPushed, true);
            app.Button_Star.Text = '*';
            app.Button_C = uibutton(app.UIFigure, 'push');
            app.Button_C.ButtonPushedFcn = createCallbackFcn(app, @Button_CPushed, true);
            app.Button_C.Text = 'C';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_B = uibutton(app.UIFigure, 'push');
            app.Button_B.ButtonPushedFcn = createCallbackFcn(app, @Button_BPushed, true);
            app.Button_B.Text = 'B';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_A = uibutton(app.UIFigure, 'push');
            app.Button_A.ButtonPushedFcn = createCallbackFcn(app, @Button_APushed, true);
            app.Button_A.Text = 'A';
            app.ForKeyboardAlreadyEntered = uieditfield(app.UIFigure, 'text');
            app.ForKeyboardAlreadyEntered.ValueChangedFcn = createCallbackFcn(app, @ForKeyboardAlreadyEnteredValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '请在此处输入';
            app.InformArea = uieditfield(app.UIFigure, 'text');
            app.InformArea.ValueChangedFcn = createCallbackFcn(app, @InformAreaValueChanged, true);
            app.InformArea.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '通知区';
            app.ForButtonAlreadyEntered = uieditfield(app.UIFigure, 'text');
            app.ForButtonAlreadyEntered.ValueChangedFcn = createCallbackFcn(app, @ForButtonAlreadyEnteredValueChanged, true);
            app.ForButtonAlreadyEntered.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '已输入';
            app.NumberLastTime = uislider(app.UIFigure);
            app.NumberLastTime.Limits = [0.5 5];
            app.NumberLastTime.MajorTicks = [0.5 1 2 3 4 5];
            app.NumberLastTime.ValueChangedFcn = createCallbackFcn(app, @NumberLastTimeValueChanged, true);
            app.NumberLastTime.Value = 0.5;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '每个号码的持续时间(s)';
            app.MaxNoiseLastTime = uislider(app.UIFigure);
            app.MaxNoiseLastTime.Limits = [0.2 5];
            app.MaxNoiseLastTime.MajorTicks = [0.2 1 2 3 4 5];
            app.MaxNoiseLastTime.MajorTickLabels = {'0.2', '1', '2', '3', '4', '5'};
            app.MaxNoiseLastTime.ValueChangedFcn = createCallbackFcn(app, @MaxNoiseLastTimeValueChanged, true);
            app.MaxNoiseLastTime.MinorTicks = [];
            app.MaxNoiseLastTime.Value = 0.2;
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '号码之间的最长间隔时间(s)';
            app.fsHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.fsHzEditField.Limits = [4000 Inf];
            app.fsHzEditField.ValueChangedFcn = createCallbackFcn(app, @fsHzEditFieldValueChanged, true);
            app.fsHzEditField.Value = 8000;
            app.fsHzEditFieldLabel = uilabel(app.UIFigure);
            app.fsHzEditFieldLabel.Text = '采样频率fs(Hz)';
            app.NumberAmplitude = uislider(app.UIFigure);
            app.NumberAmplitude.Limits = [2 10];
            app.NumberAmplitude.ValueChangedFcn = createCallbackFcn(app, @NumberAmplitudeValueChanged, true);
            app.NumberAmplitude.Value = 6;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '号码振幅';
            app.NoiseAmplitude = uislider(app.UIFigure);
            app.NoiseAmplitude.Limits = [0 0.5];
            app.NoiseAmplitude.ValueChangedFcn = createCallbackFcn(app, @NoiseAmplitudeValueChanged, true);
            app.NoiseAmplitude.Value = 0.5;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '噪声振幅';
            app.EditField_DecodeNow = uieditfield(app.UIFigure, 'text');
            app.EditField_DecodeNow.ValueChangedFcn = createCallbackFcn(app, @EditField_DecodeNowValueChanged, true);
            app.EditField_DecodeNow.Editable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '破译结果';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '破译结果';
            app.EditField_DecodeWav = uieditfield(app.UIFigure, 'text');
            app.EditField_DecodeWav.ValueChangedFcn = createCallbackFcn(app, @EditField_DecodeWavValueChanged, true);
            app.EditField_DecodeWav.Editable = 'off';
            app.EditField_CaptureMoment = uieditfield(app.UIFigure, 'text');
            app.EditField_CaptureMoment.ValueChangedFcn = createCallbackFcn(app, @EditField_CaptureMomentValueChanged, true);
            app.EditField_CaptureMoment.Editable = 'off';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '您截取的时刻为';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.InformArea_2 = uieditfield(app.UIFigure, 'text');
            app.InformArea_2.ValueChangedFcn = createCallbackFcn(app, @InformArea_2ValueChanged, true);
            app.InformArea_2.Editable = 'off';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '通知区';
            app.Button_Programmer = uibutton(app.UIFigure, 'push');
            app.Button_Programmer.ButtonPushedFcn = createCallbackFcn(app, @Button_ProgrammerPushed, true);
            app.Button_Programmer.Text = '编写者';
            app.USTCNBLabel = uilabel(app.UIFigure);
            app.USTCNBLabel.Text = 'USTCNB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DTMF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end