classdef LyxAddSin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        f1EditFieldLabel         matlab.ui.control.Label
        f1EditField              matlab.ui.control.NumericEditField
        f2EditFieldLabel         matlab.ui.control.Label
        f2EditField              matlab.ui.control.NumericEditField
        FsLabel                  matlab.ui.control.Label
        FsEditField              matlab.ui.control.NumericEditField
        msSliderLabel            matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        plotButton               matlab.ui.control.StateButton
        sin2pif1tsin2pif2tLabel  matlab.ui.control.Label
        f1EditField_2Label       matlab.ui.control.Label
        f2EditField_2            matlab.ui.control.NumericEditField
        f2EditField_2Label       matlab.ui.control.Label
        f1EditField_2            matlab.ui.control.NumericEditField
        timeUIAxes               matlab.ui.control.UIAxes
        freqUIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function LyxDraw(app)
            f1 = app.f1EditField.Value;%回调
            f2 = app.f2EditField.Value;%回调
            fs = app.FsEditField.Value ;%回调
            lent = app.Slider.Value ;%回调   %lent的单位为ms         
            t = 0:1/fs:lent*1e-3;%t的范围
            sig = sin(2*pi*f1*t) + sin(2*pi*f2*t);%制造sig
            %画时域波形
            axis(app.timeUIAxes,[0,t(end),min(sig),max(sig)*1.2]) ;
            plot(app.timeUIAxes,t,sig);
            %画幅度谱
            fsig = abs(fft(sig));%fft是matlab自带的Fourier变换的函数 %abs为取模 angle为取辐角
            Myfsig=fsig(1:end/2);%由于单边谱和双边谱的关系，此处必须取fsig的前半部分 %轩少还没看懂，请轩少再查查单边谱和双边谱
            plot(app.freqUIAxes,Myfsig); 
            %寻峰
            [~,locs] = findpeaks(Myfsig);
            My_f=fs*(locs/(lent*fs*10^(-3)+1));%换算 %轩少发现：lent越大，即采样时间越长，fft算出的f的误差越小
            if length(My_f)==1 %只有1个峰值通常是因为f1、f2相差太小，此时我只能让f1'=f2'
                app.f1EditField_2.Value=My_f(1);
                app.f2EditField_2.Value=My_f(1);
            end
            if length(My_f)==2
                app.f1EditField_2.Value=My_f(1);
                app.f2EditField_2.Value=My_f(2);
            end
            %工作区
            assignin('base','fsig',fsig);
            assignin('base','Myfsig',Myfsig);
            assignin('base','locs',locs);
            assignin('base','My_f',My_f);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             LyxDraw(app);
        end

        % Value changed function: plotButton
        function plotButtonValueChanged(app, event)
%             value = app.plotButton.Value;
            LyxDraw(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
%             value = app.Slider.Value;
            LyxDraw(app);
        end

        % Value changed function: f1EditField_2
        function f1EditField_2ValueChanged(app, event)
            
        end

        % Value changed function: f2EditField_2
        function f2EditField_2ValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.f1EditFieldLabel = uilabel(app.UIFigure);
            app.f1EditFieldLabel.Text = 'f1';
            app.f1EditField = uieditfield(app.UIFigure, 'numeric');
            app.f1EditField.Value = 1000;
            app.f2EditFieldLabel = uilabel(app.UIFigure);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.UIFigure, 'numeric');
            app.f2EditField.Value = 1001;
            app.FsLabel = uilabel(app.UIFigure);
            app.FsLabel.Text = '采样频率Fs';
            app.FsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FsEditField.Value = 10000;
            app.msSliderLabel = uilabel(app.UIFigure);
            app.msSliderLabel.Text = '信号时长(ms)';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [100 10000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10000;
            app.plotButton = uibutton(app.UIFigure, 'state');
            app.plotButton.ValueChangedFcn = createCallbackFcn(app, @plotButtonValueChanged, true);
            app.plotButton.Text = 'plot';
            app.sin2pif1tsin2pif2tLabel = uilabel(app.UIFigure);
            app.sin2pif1tsin2pif2tLabel.Text = 'sin(2*pi*f1*t)  + sin(2*pi*f2*t)';
            app.f1EditField_2Label = uilabel(app.UIFigure);
            app.f1EditField_2Label.Text = 'f1''';
            app.f2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.f2EditField_2.ValueChangedFcn = createCallbackFcn(app, @f2EditField_2ValueChanged, true);
            app.f2EditField_2Label = uilabel(app.UIFigure);
            app.f2EditField_2Label.Text = 'f2''';
            app.f1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.f1EditField_2.ValueChangedFcn = createCallbackFcn(app, @f1EditField_2ValueChanged, true);
            app.timeUIAxes = uiaxes(app.UIFigure);
            title(app.timeUIAxes, 'A-t图')
            xlabel(app.timeUIAxes, 'X')
            ylabel(app.timeUIAxes, 'Y')
            zlabel(app.timeUIAxes, 'Z')
            app.freqUIAxes = uiaxes(app.UIFigure);
            title(app.freqUIAxes, 'A-f图')
            xlabel(app.freqUIAxes, 'X')
            ylabel(app.freqUIAxes, 'Y')
            zlabel(app.freqUIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LyxAddSin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end