classdef Epicycloid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        RotationSpeed     matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        k                 matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function func(app)     
            %% 画静态图

            %画曲线
            r=1;%r为小圆半径
            R=app.k.Value*r;%R为大圆半径 %k为半径之比
            theta=0:0.01:2*pi*10;
            x=(R+r)*cos(theta)-r*cos((R+r)/r*theta);
            y=(R+r)*sin(theta)-r*sin((R+r)/r*theta);
%             axis equal tight;
%             axis ((R+2*r)*[-1 1 -1 1]);
            %画静圆
            theta1=0:0.01:2*pi;
            x1=R*cos(theta1);
            y1=R*sin(theta1);
            plot(app.UIAxes,x,y,'blue',x1,y1,'black');
            hold(app.UIAxes,'on'); 

            %% 画动态图
            lyx = plot(app.UIAxes,x(1),y(1),'marker','o','markersize',5,'markerfacecolor','green');%造出一个圆
            N = length(theta);
            TimeInterval=40;%时间间隔 %时间间隔越大，转速越快
            for i=1:TimeInterval:N
                set(lyx,'xdata',x(i),'ydata',y(i));
                %画动圆
                %(x0,y0)为动圆圆心的坐标
                x0=(R+r)*cos(theta(i));
                y0=(R+r)*sin(theta(i));
                %用参数方程画动圆
                theta2=0:0.01:2*pi;
                x2=r*cos(theta2)+x0;
                y2=r*sin(theta2)+y0;
                h2=plot(app.UIAxes,x2,y2);
                %连接小圆圆心和外摆线上的点
                h3=line(app.UIAxes,[x0,x(i)],[y0,y(i)]); 
                % F = getframe;
                delete(h2);%删掉h2
                delete(h3);%删掉h3
                if i+TimeInterval>N %画最后一个图
                    i=N;
                    set(lyx,'xdata',x(i),'ydata',y(i));
                    %画动圆
                    %(x0,y0)为动圆圆心的坐标
                    x0=(R+r)*cos(theta(i));
                    y0=(R+r)*sin(theta(i));
                    %用参数方程画动圆
                    theta2=0:0.01:2*pi;
                    x2=r*cos(theta2)+x0;
                    y2=r*sin(theta2)+y0;
                    h2=plot(app.UIAxes,x2,y2,'b');
                    %连接动圆 圆心和外摆线上的点
                    h3=line(app.UIAxes,[x0,x(i)],[y0,y(i)]);    
                    % F = getframe;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func(app);
        end

        % Value changed function: k
        function kValueChanged(app, event)
            func(app);
        end

        % Value changed function: RotationSpeed
        function RotationSpeedValueChanged(app, event)
            value = app.RotationSpeed.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '动圆半径/静圆半径';
            app.k = uieditfield(app.UIFigure, 'numeric');
            app.k.ValueChangedFcn = createCallbackFcn(app, @kValueChanged, true);
            app.k.Value = 3;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '转动速度';
            app.RotationSpeed = uieditfield(app.UIFigure, 'numeric');
            app.RotationSpeed.ValueChangedFcn = createCallbackFcn(app, @RotationSpeedValueChanged, true);
            app.RotationSpeed.Value = 20;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '提示：试试动圆半径/静圆半径=1、1/5、1/10吧！';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Epicycloid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end