classdef Exponential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        variance                matlab.ui.control.NumericEditField
        varianceEditFieldLabel  matlab.ui.control.Label
        mean                    matlab.ui.control.NumericEditField
        meanEditFieldLabel      matlab.ui.control.Label
        drawButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function DrawExponential(app)
            
            x_mean = app.mean.Value;
            x = 0:0.1:10;
            y = 1/x_mean*exp(-x/x_mean);
            plot(app.UIAxes,x,y);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DrawExponential(app);
            app.variance.Value = app.mean.Value^2;
        end

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
            DrawExponential(app);
        end

        % Value changed function: mean
        function meanValueChanged(app, event)
            % value = app.mean.Value;
            DrawExponential(app);
            app.variance.Value = app.mean.Value^2;
        end

        % Value changed function: variance
        function varianceValueChanged(app, event)
            % value = app.variance.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Exponential Distribution')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.drawButton = uibutton(app.UIFigure, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'draw';
            app.meanEditFieldLabel = uilabel(app.UIFigure);
            app.meanEditFieldLabel.Text = 'mean';
            app.mean = uieditfield(app.UIFigure, 'numeric');
            app.mean.ValueChangedFcn = createCallbackFcn(app, @meanValueChanged, true);
            app.mean.Value = 1;
            app.varianceEditFieldLabel = uilabel(app.UIFigure);
            app.varianceEditFieldLabel.Text = 'variance';
            app.variance = uieditfield(app.UIFigure, 'numeric');
            app.variance.ValueChangedFcn = createCallbackFcn(app, @varianceValueChanged, true);
            app.variance.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exponential

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end